classdef HUD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        XXLabel_2             matlab.ui.control.Label
        BatteryLifeLabel      matlab.ui.control.Label
        XXpsiLabel            matlab.ui.control.Label
        SuitPressureLabel     matlab.ui.control.Label
        OxygenLevelLabel      matlab.ui.control.Label
        NominalLabel          matlab.ui.control.Label
        CO2ScrubberLabel      matlab.ui.control.Label
        WARNINGLamp           matlab.ui.control.Lamp
        WARNINGLampLabel      matlab.ui.control.Label
        PlaySimulationButton  matlab.ui.control.Button
        CompassAxes           matlab.ui.control.UIAxes
        UIOxygenAxes          matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIOxygenAxes.Title.Color = 'w';  % Change title font color to white
            % since apparently this isn't an option in design viewer, so we
            % have to manually do it  
            
            % manually changing some compass features
            app.CompassAxes.XColor = 'none';
            app.CompassAxes.YColor = 'none';
            app.CompassAxes.Color = 'none'; % Transparent background
            app.CompassAxes.Box = 'off'; % Remove box outline
            axis(app.CompassAxes, 'equal');
            hold(app.CompassAxes, 'on');
            
            % static compass rose
            theta = linspace(0, 2*pi, 100);
            x = cos(theta);
            y = sin(theta);
            plot(app.CompassAxes, x, y, 'w'); % circle outline for compass
            

            text(app.CompassAxes, 0, 1.2, 'N', 'HorizontalAlignment', 'center', 'Color', 'w','FontName', 'Consolas');
            text(app.CompassAxes, 1.2, 0, 'E', 'HorizontalAlignment', 'center', 'Color', 'w','FontName', 'Consolas');
            text(app.CompassAxes, 0, -1.2, 'S', 'HorizontalAlignment', 'center', 'Color', 'w','FontName', 'Consolas');
            text(app.CompassAxes, -1.2, 0, 'W', 'HorizontalAlignment', 'center', 'Color', 'w','FontName', 'Consolas');
            
   
        end

        % Button pushed function: PlaySimulationButton
        function PlaySimulationButtonPushed(app, event)
            % When play simulation button is pushed, read in HUDFootage and
            % display it within our figure (UIAxes)
            filename = 'data/HUDFootage.mp4';
            v = VideoReader(filename);
            frame = readFrame(v);
            im_frame = imshow(frame, 'Parent', app.UIAxes);
           
            % Initialize dynamic heading pointer
            headingAngle = 90; % Initial heading, update dynamically
            xPointer = [0, cosd(headingAngle)];
            yPointer = [0, sind(headingAngle)];
            compassPointer = plot(app.CompassAxes, xPointer, yPointer, 'r', 'LineWidth', 2);


            % load display data
            displayData = load('data/displayData.csv');
            oxygenData = displayData(2,:)'; % column vector made from row 2 of csv
            co2Data = displayData(3,:)'; % column vector made from row 3 of csv

            % load event data
            eventData = load('data/eventData.csv');
            % Change the name below to something like "hypoxiaTrigger" once
            % it actually represents something
            trigger1Data = eventData(2,:)'; % column vector made from row 2 of csv

            % Set font sizes
            nominalFont = 12;
            offnominalFont = 15;

            % Prepare oxygen bar
            hold(app.UIOxygenAxes, 'on');
            oxygenBar = rectangle(app.UIOxygenAxes,...
                'Position', [0, 0, 1, 0],...
                'FaceColor', 'g',...
                'EdgeColor','none');
            ylim(app.UIOxygenAxes, [0, 1]); % Normalize to percentage (0-1)
            hold(app.UIOxygenAxes, 'off');

            % Normalize oxygen data to [0, 1] for the bar
            normalizedOxygen = max(0,min(oxygenData,1)); % making oxygen be 0-100 range
            
            % loop through entire video length
            frameIdx = 1;
            % Set initial alert flag equal to 0 (ie no alerts)
            alertFlag = 0;
            while hasFrame(v) && frameIdx <= length(normalizedOxygen)
                im_frame.CData = readFrame(v);

                % Add event triggers if applicable
                % Hypoxia Trigger

                disp(frameIdx)
                disp(trigger1Data(frameIdx))

                % if the alert boxes already exist, delete them
                if alertFlag == 1
                    % This will prevent us from re-drawing the same box on
                    % top of previous alert boxes and also make sure that
                    % we get rid of the alert after it's gone
                    delete(alertRect);
                    delete(alertText);
                end

                if trigger1Data(frameIdx) == 1

                    width = 1000; % width: half of screen
                    height = 500; % heigh: half of screen
                    xCenter = 1000; % Center alert box in middle of screen
                    yCenter = 500; % Center alert box in middle of screen
                    xLeft = xCenter - width/2;
                    yBottom = yCenter - height/2;
                    alertRect = rectangle(app.UIAxes,...
                        'Position', [xLeft, yBottom, width, height],...
                        'FaceColor', [1, 0, 0, 0.5],...
                        'EdgeColor', [1, 0, 0, 0.5],...
                        'Curvature',0.2);
                    alertText = text(app.UIAxes,xCenter,yCenter,"HYPOXIA ALERT",...
                        'HorizontalAlignment','center',...
                        'FontSize',32,...
                        'FontWeight','bold',...
                        'Color','w');
                    % Let us know in next run-through of the loop that we
                    % had an alert last loop
                    alertFlag = 1;

                    % Adaptive Interface Based on Alert
                    % For hypoxia for example, increase the font sizes
                    % Increase font sizes
                    app.OxygenLevelLabel.FontSize = offnominalFont;
                    app.NominalLabel.FontSize = offnominalFont;

                    % Set CO2 scrubber to invisible
                    app.CO2ScrubberLabel.Visible = "off";
                    app.NominalLabel.Visible = "off";
                    

                else
                    % Let us know in next run-through of the loop that we
                    % had NO alert last loop
                    alertFlag = 0;
                    % re-set the font sizes back to normal
                    app.OxygenLevelLabel.FontSize = nominalFont;
                    app.NominalLabel.FontSize = nominalFont;
                    % Set CO2 scrubber to invisible
                    app.CO2ScrubberLabel.Visible = "on";
                    app.NominalLabel.Visible = "on";
                end

                % Update oxygen bar
                currentLevel = normalizedOxygen(frameIdx); % Get current oxygen level
                oxygenBar.Position = [0, 0, 1, currentLevel]; % Adjust bar height
               
                % Update the heading pointer dynamically (example: vary angle)
                headingAngle = mod(headingAngle + 1, 360); % Example: Increment heading
                xPointer = [0, cosd(headingAngle)];
                yPointer = [0, sind(headingAngle)];
                compassPointer.XData = xPointer;
                compassPointer.YData = yPointer;
                
                % Update lamp color and text based on oxygen level
                if currentLevel < 0.5  % If oxygen level is below 50%
                    app.WARNINGLamp.Color = 'red'; % Set lamp color to red for warning
                    app.WARNINGLampLabel.Text = 'WARNING: LOW O2';
                else
                    app.WARNINGLamp.Color = 'green'; % Set lamp color to green if oxygen is safe
                    app.WARNINGLampLabel.Text = "O2 Nominal";
                end

                % Update CO2 scrubber status (for now set to nominal if
                % it's above 0.1
                if co2Data(frameIdx) < 0.1  % If CO2 value is below 10%
                    app.NominalLabel.FontColor = 'red'; 
                    app.NominalLabel.Text = 'Off-Nominal'; 
                else
                    app.NominalLabel.FontColor = 'green'; 
                    app.NominalLabel.Text = 'Nominal'; 
                end
                
                % increment frame index
                frameIdx = frameIdx + 1;
                
                % Draw video frame onto axes
                drawnow % this refreshes the UI

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIOxygenAxes = uiaxes(app.UIFigure);
            zlabel(app.UIOxygenAxes, 'Z')
            app.UIOxygenAxes.XTick = [];
            app.UIOxygenAxes.YTick = [0 0.5 1];
            app.UIOxygenAxes.YTickLabel = {'0'; '50%'; '100%'};
            app.CompassAxes = uiaxes(app.UIFigure);
            app.CompassAxes.XTick = [];
            app.CompassAxes.YTick = [];
            app.PlaySimulationButton = uibutton(app.UIFigure, 'push');
            app.PlaySimulationButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySimulationButtonPushed, true);
            app.PlaySimulationButton.Text = 'Play Simulation';
            app.WARNINGLampLabel = uilabel(app.UIFigure);
            app.WARNINGLampLabel.Text = 'WARNING';
            app.WARNINGLamp = uilamp(app.UIFigure);
            app.CO2ScrubberLabel = uilabel(app.UIFigure);
            app.CO2ScrubberLabel.Text = 'CO2 Scrubber:';
            app.NominalLabel = uilabel(app.UIFigure);
            app.NominalLabel.Text = 'Nominal';
            app.OxygenLevelLabel = uilabel(app.UIFigure);
            app.OxygenLevelLabel.Text = 'Oxygen Level';
            app.SuitPressureLabel = uilabel(app.UIFigure);
            app.SuitPressureLabel.Text = 'Suit Pressure:';
            app.XXpsiLabel = uilabel(app.UIFigure);
            app.XXpsiLabel.Text = 'XXpsi';
            app.BatteryLifeLabel = uilabel(app.UIFigure);
            app.BatteryLifeLabel.Text = 'Battery Life:';
            app.XXLabel_2 = uilabel(app.UIFigure);
            app.XXLabel_2.Text = 'XX%';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HUD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end